classdef test9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        contour3Button  matlab.ui.control.Button
        surfButton      matlab.ui.control.Button
        meshButton      matlab.ui.control.Button
        Button          matlab.ui.control.Button
        ButtonGroup     matlab.ui.container.ButtonGroup
        sincButton      matlab.ui.control.RadioButton
        membraneButton  matlab.ui.control.RadioButton
        peaksButton     matlab.ui.control.RadioButton
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        z = peaks; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'peaks'
                    app.z = peaks;
                case 'membrane'
                    app.z = membrane;
                case 'sinc'
                    t = -2:0.1:2;
                    [x,y] = meshgrid(t);
                    app.z = sinc(x).*sinc(y);
            end
        end

        % Callback function: Button, UIAxes
        function ButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: meshButton
        function meshButtonPushed(app, event)
            mesh(app.UIAxes,app.z);
        end

        % Button pushed function: surfButton
        function surfButtonPushed(app, event)
            surf(app.UIAxes,app.z);
        end

        % Button pushed function: contour3Button
        function contour3ButtonPushed(app, event)
            contour3(app.UIAxes,app.z);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {449, 449};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '选择数据';
            app.peaksButton = uiradiobutton(app.ButtonGroup);
            app.peaksButton.Text = 'peaks';
            app.peaksButton.Value = true;
            app.membraneButton = uiradiobutton(app.ButtonGroup);
            app.membraneButton.Text = 'membrane';
            app.sincButton = uiradiobutton(app.ButtonGroup);
            app.sincButton.Text = 'sinc';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '退出';
            app.meshButton = uibutton(app.LeftPanel, 'push');
            app.meshButton.ButtonPushedFcn = createCallbackFcn(app, @meshButtonPushed, true);
            app.meshButton.Text = 'mesh';
            app.surfButton = uibutton(app.LeftPanel, 'push');
            app.surfButton.ButtonPushedFcn = createCallbackFcn(app, @surfButtonPushed, true);
            app.surfButton.Text = 'surf';
            app.contour3Button = uibutton(app.LeftPanel, 'push');
            app.contour3Button.ButtonPushedFcn = createCallbackFcn(app, @contour3ButtonPushed, true);
            app.contour3Button.Text = 'contour3';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '绘图区')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end